classdef GUI_version < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrainButton                     matlab.ui.control.Button
        TestButton                      matlab.ui.control.Button
        OpenCSVfileButton               matlab.ui.control.Button
        NumberofHiddenNeuronsEditFieldLabel  matlab.ui.control.Label
        NumberofHiddenNeuronsEditField  matlab.ui.control.NumericEditField
        NumberofInputDaysEditFieldLabel  matlab.ui.control.Label
        NumberofInputDaysEditField      matlab.ui.control.NumericEditField
        NumberofPredictedDaysEditFieldLabel  matlab.ui.control.Label
        NumberofPredictedDaysEditField  matlab.ui.control.NumericEditField
        BiasValueEditFieldLabel         matlab.ui.control.Label
        BiasValueEditField              matlab.ui.control.NumericEditField
        ShowpredictionforsamplenumberEditFieldLabel  matlab.ui.control.Label
        ShowpredictionforsamplenumberEditField  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        CreateELMButton                 matlab.ui.control.Button
        ActivationFunctionEditFieldLabel  matlab.ui.control.Label
        ActivationFunctionEditField     matlab.ui.control.EditField
        ProportionsTrainDataTestDataSliderLabel  matlab.ui.control.Label
        ProportionsTrainDataTestDataSlider  matlab.ui.control.Slider
        NumberoftestsamplesEditFieldLabel  matlab.ui.control.Label
        NumberoftestsamplesEditField    matlab.ui.control.NumericEditField
        timesEditFieldLabel             matlab.ui.control.Label
        timesEditField                  matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        OpenMSEEditFieldLabel           matlab.ui.control.Label
        OpenMSEEditField                matlab.ui.control.NumericEditField
        HighMSEEditFieldLabel           matlab.ui.control.Label
        HighMSEEditField                matlab.ui.control.NumericEditField
        LowMSEEditFieldLabel            matlab.ui.control.Label
        LowMSEEditField                 matlab.ui.control.NumericEditField
        CloseMSEEditFieldLabel          matlab.ui.control.Label
        CloseMSEEditField               matlab.ui.control.NumericEditField
        MSEerrorsforcurrentsampleLabel  matlab.ui.control.Label
        MSEerrorforallsamplesLabel      matlab.ui.control.Label
        HighMSEEditField_2Label         matlab.ui.control.Label
        HighMSEEditField_2              matlab.ui.control.NumericEditField
        LowMSEEditField_2Label          matlab.ui.control.Label
        LowMSEEditField_2               matlab.ui.control.NumericEditField
        CloseMSEEditField_2Label        matlab.ui.control.Label
        CloseMSEEditField_2             matlab.ui.control.NumericEditField
        OpenMSEEditField_2Label         matlab.ui.control.Label
        OpenMSEEditField_2              matlab.ui.control.NumericEditField
        UIAxesImage                     matlab.ui.control.UIAxes
        MateuszGrossmanLabel            matlab.ui.control.Label
        FilipMatrackiLabel              matlab.ui.control.Label
        FardinMohammedLabel             matlab.ui.control.Label
        CurrentlyOpenedfileEditFieldLabel  matlab.ui.control.Label
        CurrentlyOpenedfileEditField    matlab.ui.control.EditField
    end


    properties (Access = private)
        file % csv file after parsing it to table
        elm  % our elm
        testX
        testY
        predictionoftestY
        trainX
        trainY
        nFeatures
        %testset price matrices for mse computing:
        %predicted arrays
        predOpenPricesM
        predHighPricesM
        predLowPricesM
        predClosePricesM
        %real ones
        openPricesM
        highPricesM
        lowPricesM
        closePricesM
        %mse's
        openMSE
        highMSE
        lowMSE
        closeMSE
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('mini_logo.PNG','Parent',app.UIAxesImage)
        end

        % Button pushed function: OpenCSVfileButton
        function OpenCSVfileButtonPushed(app, event)
            filename=uigetfile('*.csv');
            if(filename~=0)
              app.file = ParseCSV(filename);
              app.CreateELMButton.Enable='on';
              app.CurrentlyOpenedfileEditField.Value=sprintf('%s',filename);
            end
        end

        % Button pushed function: CreateELMButton
        function CreateELMButtonPushed(app, event)
            app.elm=ELM_Class(app.NumberofInputDaysEditField.Value,...
                app.NumberofHiddenNeuronsEditField.Value,...
                app.NumberofPredictedDaysEditField.Value,size(app.file,2),...
                app.ActivationFunctionEditField.Value,app.BiasValueEditField.Value);
            app.nFeatures = size(app.file,2);
            app.TrainButton.Enable='on';
            [oX,oY]=rearrangeData(app.elm,app.file);
            [app.testX,app.testY,app.trainX,app.trainY] = setProportionsOfData(oX,oY,app.ProportionsTrainDataTestDataSlider.Value);
            app.NumberoftestsamplesEditField.Value=size(app.testY,1);
            % change upper limit to size of testsamples
            %app.ShowpredictionforsamplenumberEditField.UpperLimitInclusive=size(app.testY,1);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            tic
            app.elm = train(app.elm,app.trainX,app.trainY);
            app.timesEditField.Value = toc;
            app.TestButton.Enable= 'on';
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.predictionoftestY = predict(app.elm,app.testX);
            app.ShowpredictionforsamplenumberEditField.Enable='on';
            app.predOpenPricesM= app.predictionoftestY(:,1:app.nFeatures:end);
            app.predHighPricesM= app.predictionoftestY(:,2:app.nFeatures:end);
            app.predLowPricesM= app.predictionoftestY(:,3:app.nFeatures:end);
            app.predClosePricesM= app.predictionoftestY(:,4:app.nFeatures:end);
            app.openPricesM= app.testY(:,1:app.nFeatures:end);
            app.highPricesM= app.testY(:,2:app.nFeatures:end);
            app.lowPricesM= app.testY(:,3:app.nFeatures:end);
            app.closePricesM= app.testY(:,4:app.nFeatures:end);
            
            app.openMSE= immse(app.openPricesM,app.predOpenPricesM);
            app.highMSE= immse(app.highPricesM,app.predHighPricesM);
            app.lowMSE= immse(app.lowPricesM,app.predLowPricesM);
            app.closeMSE= immse(app.closePricesM,app.predClosePricesM);
            
            %MSEs for all samples
            app.OpenMSEEditField_2.Value = app.openMSE;
            app.HighMSEEditField_2.Value = app.highMSE;
            app.LowMSEEditField_2.Value = app.lowMSE;
            app.CloseMSEEditField_2.Value = app.closeMSE;
            
        end

        % Value changed function: 
        % ShowpredictionforsamplenumberEditField
        function ShowpredictionforsamplenumberEditFieldValueChanged(app, event)
            value = app.ShowpredictionforsamplenumberEditField.Value;
            t=1:getnumofOutdays(app.elm);
            %legend not working as it should fix it later
            % for now it only shows predicted open prices vs real open prices
            %legend(app.UIAxes.Legend, 'predicted Open','real Open');
           %app.UIAxes.Legend = legend('a','b');
            plot(app.UIAxes,t,app.predictionoftestY(value,1: app.nFeatures : end) ,'b'...
                ,t,app.testY(value,1: app.nFeatures : end),'r');
             plot(app.UIAxes2,t,app.predictionoftestY(value,2: app.nFeatures : end) ,'b'...
                ,t,app.testY(value,2: app.nFeatures : end),'r');
             plot(app.UIAxes3,t,app.predictionoftestY(value,3: app.nFeatures : end) ,'b'...
                ,t,app.testY(value,3: app.nFeatures : end),'r');
             plot(app.UIAxes4,t,app.predictionoftestY(value,4: app.nFeatures : end) ,'b'...
                ,t,app.testY(value,4: app.nFeatures : end),'r');
           legend(app.UIAxes,'predicted value','real value');
           
           legend(app.UIAxes2,'predicted value','real value');
           
           legend(app.UIAxes3,'predicted value','real value');
           
           legend(app.UIAxes4,'predicted value','real value');
           
           mse=ComputeMSE(app.testY(value,:),app.predictionoftestY(value,:),app.nFeatures);
           app.OpenMSEEditField.Value= mse(1);
           app.HighMSEEditField.Value = mse(2);
           app.LowMSEEditField.Value= mse(3);
           app.CloseMSEEditField.Value= mse(4);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Enable = 'off';
            app.TestButton.Text = 'Test';
            app.OpenCSVfileButton = uibutton(app.UIFigure, 'push');
            app.OpenCSVfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCSVfileButtonPushed, true);
            app.OpenCSVfileButton.Text = 'Open CSV file';
            app.NumberofHiddenNeuronsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofHiddenNeuronsEditFieldLabel.Text = 'Number of Hidden Neurons';
            app.NumberofHiddenNeuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofHiddenNeuronsEditField.Limits = [2 10000];
            app.NumberofHiddenNeuronsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofHiddenNeuronsEditField.Value = 30;
            app.NumberofInputDaysEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofInputDaysEditFieldLabel.Text = 'Number of Input Days';
            app.NumberofInputDaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofInputDaysEditField.Limits = [1 Inf];
            app.NumberofInputDaysEditField.ValueDisplayFormat = '%.0f';
            app.NumberofInputDaysEditField.Value = 30;
            app.NumberofPredictedDaysEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPredictedDaysEditFieldLabel.Text = 'Number of Predicted Days';
            app.NumberofPredictedDaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPredictedDaysEditField.Limits = [1 Inf];
            app.NumberofPredictedDaysEditField.ValueDisplayFormat = '%.0f';
            app.NumberofPredictedDaysEditField.Value = 10;
            app.BiasValueEditFieldLabel = uilabel(app.UIFigure);
            app.BiasValueEditFieldLabel.Text = 'Bias Value';
            app.BiasValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.BiasValueEditField.ValueDisplayFormat = '%.0f';
            app.BiasValueEditField.Value = 1;
            app.ShowpredictionforsamplenumberEditFieldLabel = uilabel(app.UIFigure);
            app.ShowpredictionforsamplenumberEditFieldLabel.Text = 'Show prediction for sample number:';
            app.ShowpredictionforsamplenumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShowpredictionforsamplenumberEditField.Limits = [0 Inf];
            app.ShowpredictionforsamplenumberEditField.ValueDisplayFormat = '%.0f';
            app.ShowpredictionforsamplenumberEditField.ValueChangedFcn = createCallbackFcn(app, @ShowpredictionforsamplenumberEditFieldValueChanged, true);
            app.ShowpredictionforsamplenumberEditField.Enable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Predicted Open Price vs Real Open Price')
            xlabel(app.UIAxes, 'Day')
            ylabel(app.UIAxes, 'Price')
            app.CreateELMButton = uibutton(app.UIFigure, 'push');
            app.CreateELMButton.ButtonPushedFcn = createCallbackFcn(app, @CreateELMButtonPushed, true);
            app.CreateELMButton.Enable = 'off';
            app.CreateELMButton.Text = 'Create ELM';
            app.ActivationFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.ActivationFunctionEditFieldLabel.Text = 'Activation Function';
            app.ActivationFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.ActivationFunctionEditField.Value = 'linear';
            app.ProportionsTrainDataTestDataSliderLabel = uilabel(app.UIFigure);
            app.ProportionsTrainDataTestDataSliderLabel.Text = 'Proportions TrainData/TestData';
            app.ProportionsTrainDataTestDataSlider = uislider(app.UIFigure);
            app.ProportionsTrainDataTestDataSlider.Limits = [50 95];
            app.ProportionsTrainDataTestDataSlider.MajorTicks = [50 55 60 65 70 75 80 85 90 95];
            app.ProportionsTrainDataTestDataSlider.Value = 80;
            app.NumberoftestsamplesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoftestsamplesEditFieldLabel.Text = 'Number of test samples: ';
            app.NumberoftestsamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoftestsamplesEditField.Limits = [0 Inf];
            app.NumberoftestsamplesEditField.Editable = 'off';
            app.timesEditFieldLabel = uilabel(app.UIFigure);
            app.timesEditFieldLabel.Text = 'time(s)';
            app.timesEditField = uieditfield(app.UIFigure, 'numeric');
            app.timesEditField.Editable = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Predicted High Price vs Real High Price')
            xlabel(app.UIAxes2, 'Day')
            ylabel(app.UIAxes2, 'Price')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Predicted Low Price vs Real Low Price')
            xlabel(app.UIAxes3, 'Day')
            ylabel(app.UIAxes3, 'Price')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Predicted Close Price vs Real Close Price')
            xlabel(app.UIAxes4, 'Day')
            ylabel(app.UIAxes4, 'Price')
            app.OpenMSEEditFieldLabel = uilabel(app.UIFigure);
            app.OpenMSEEditFieldLabel.Text = 'Open MSE';
            app.OpenMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.OpenMSEEditField.Editable = 'off';
            app.HighMSEEditFieldLabel = uilabel(app.UIFigure);
            app.HighMSEEditFieldLabel.Text = 'High MSE';
            app.HighMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighMSEEditField.Editable = 'off';
            app.LowMSEEditFieldLabel = uilabel(app.UIFigure);
            app.LowMSEEditFieldLabel.Text = 'Low MSE';
            app.LowMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowMSEEditField.Editable = 'off';
            app.CloseMSEEditFieldLabel = uilabel(app.UIFigure);
            app.CloseMSEEditFieldLabel.Text = 'Close MSE';
            app.CloseMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloseMSEEditField.Editable = 'off';
            app.MSEerrorsforcurrentsampleLabel = uilabel(app.UIFigure);
            app.MSEerrorsforcurrentsampleLabel.Text = 'MSE errors for current sample:';
            app.MSEerrorforallsamplesLabel = uilabel(app.UIFigure);
            app.MSEerrorforallsamplesLabel.Text = 'MSE error for all samples:';
            app.HighMSEEditField_2Label = uilabel(app.UIFigure);
            app.HighMSEEditField_2Label.Text = 'High MSE';
            app.HighMSEEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HighMSEEditField_2.Editable = 'off';
            app.LowMSEEditField_2Label = uilabel(app.UIFigure);
            app.LowMSEEditField_2Label.Text = 'Low MSE';
            app.LowMSEEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LowMSEEditField_2.Editable = 'off';
            app.CloseMSEEditField_2Label = uilabel(app.UIFigure);
            app.CloseMSEEditField_2Label.Text = 'Close MSE';
            app.CloseMSEEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CloseMSEEditField_2.Editable = 'off';
            app.OpenMSEEditField_2Label = uilabel(app.UIFigure);
            app.OpenMSEEditField_2Label.Text = 'Open MSE';
            app.OpenMSEEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.OpenMSEEditField_2.Editable = 'off';
            app.UIAxesImage = uiaxes(app.UIFigure);
            app.UIAxesImage.XTick = [];
            app.UIAxesImage.YTick = [];
            app.MateuszGrossmanLabel = uilabel(app.UIFigure);
            app.MateuszGrossmanLabel.Text = 'Mateusz Grossman';
            app.FilipMatrackiLabel = uilabel(app.UIFigure);
            app.FilipMatrackiLabel.Text = 'Filip Matracki';
            app.FardinMohammedLabel = uilabel(app.UIFigure);
            app.FardinMohammedLabel.Text = 'Fardin Mohammed';
            app.CurrentlyOpenedfileEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentlyOpenedfileEditFieldLabel.Text = 'Currently Opened file:';
            app.CurrentlyOpenedfileEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentlyOpenedfileEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_version

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end